classdef QuantificationChannelChooser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EachpatternonanewlineEditField  matlab.ui.control.TextArea
        EachpatternonanewlineTextAreaLabel  matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
    end

    
    properties (Access = private)
        lines = {}; % Description
        appOutput = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, current_input, appOutput)
            app.lines = strsplit(current_input, ';');
            app.EachpatternonanewlineEditField.Value = app.lines;
            app.appOutput = appOutput;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.appOutput.setOutput(app.EachpatternonanewlineEditField.Value);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.EachpatternonanewlineTextAreaLabel = uilabel(app.UIFigure);
            app.EachpatternonanewlineTextAreaLabel.Text = {'Each pattern'; ' on a new line'};
            app.EachpatternonanewlineEditField = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuantificationChannelChooser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end